classdef PlantSeg2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MainTab                      matlab.ui.container.Tab
        ClickFunctionButtonGroup     matlab.ui.container.ButtonGroup
        ZoomInButton                 matlab.ui.control.ToggleButton
        Button3                      matlab.ui.control.ToggleButton
        Button2                      matlab.ui.control.ToggleButton
        Button                       matlab.ui.control.ToggleButton
        SliderMaskAlpha              matlab.ui.control.Slider
        MaskAlphaLabel               matlab.ui.control.Label
        OpenDirButton                matlab.ui.control.Button
        MiscTab                      matlab.ui.container.Tab
        rootLabel                    matlab.ui.control.Label
        shootLabel                   matlab.ui.control.Label
        ColorSelectorMaskRoot        wt.ColorSelector
        ColorSelectorMaskShoot       wt.ColorSelector
        backgroundLabel              matlab.ui.control.Label
        ColorSelectorMaskBackground  wt.ColorSelector
        ShowmaskbackgroundcolorCheckBox  matlab.ui.control.CheckBox
        UIAxesSeg                    matlab.ui.control.UIAxes
    end

    
    % PPrivate variables:
    properties (Access = private)
        bgMaskValue = 0;    
        shootMaskValue = 127;
        rootMaskValue = 255;
        bgColor = [0, 0, 0];
        shootColor = [0, 0, 1];
        rootColor = [1, 0, 0];
        inputImage = 0;         % input image with 3 channels RGB of uint8
        mask = 0;               % uint8 mask
        maskAlpha = 0.5;        % mask transparency
        imagePlot = 0;
        showBackgroundColor = false;
        
        mouseDown = false;
    end
    
    % Private functions:
    methods (Access = private)
        
        function UpdateSegmentation(app)

            % Remove title, axis labels, and tick labels
            title(app.UIAxesSeg, []);
            xlabel(app.UIAxesSeg, []);
            ylabel(app.UIAxesSeg, []);
            app.UIAxesSeg.XAxis.TickLabels = {};
            app.UIAxesSeg.YAxis.TickLabels = {};
            % Display image and stretch to fill axes

            
            imshow(app.inputImage, "Parent", app.UIAxesSeg, "Border", "tight", "InitialMagnification", "fit");%,...
                %'XData', [1 app.UIAxesSeg.Position(3)], ...
                %'YData', [1 app.UIAxesSeg.Position(4)]);
            hold(app.UIAxesSeg, "on");
            app.imagePlot = imshow(app.mask, "Parent", app.UIAxesSeg, "Border", "tight", "InitialMagnification", "fit"); %,...
                %'XData', [1 app.UIAxesSeg.Position(3)], ...
                %'YData', [1 app.UIAxesSeg.Position(4)]);
            segColorMap = [app.bgColor; app.shootColor; app.rootColor];
            colormap(app.UIAxesSeg, segColorMap);

            % Set limits of axes
            app.UIAxesSeg.XLim = [0 app.imagePlot.XData(2)];
            app.UIAxesSeg.YLim = [0 app.imagePlot.YData(2)];

            hold(app.UIAxesSeg, "off")
            
            alphaImage = ComputeMaskAlphaImage(app);
            set(app.imagePlot, 'AlphaData', alphaImage);
            set(app.imagePlot, 'Tag', 'WindowTag')
        end
        
        function InitializeVariables(app)
            app.SliderMaskAlpha.Value = app.maskAlpha;
            app.ShowmaskbackgroundcolorCheckBox.Value = app.showBackgroundColor;
            app.ColorSelectorMaskBackground.Value = app.bgColor;
            app.ColorSelectorMaskShoot.Value = app.shootColor;
            app.ColorSelectorMaskRoot.Value = app.rootColor;
        end
        
        function ComputeMaskFromInput(app)
            app.mask = rgb2gray(app.inputImage);
            app.mask = (uint8(app.mask>=100 & app.mask<=149)*127) + (uint8(app.mask>=150 & app.mask<=255)*255);
        end
        
        function UpdateMaskFromMouse(app, event)
            mouseButtonFlat = get(event.Source,'SelectionType');
            mousePos = get(app.UIAxesSeg,'CurrentPoint');
            mouseRowPos = round(mousePos(1,2));
            mouseColPos = round(mousePos(1,1))

            app.mask(mouseRowPos, mouseColPos) = app.rootMaskValue;
            alphaImage = ComputeMaskAlphaImage(app);
            %UpdateSegmentation(app);
            %app.imagePlot.CData(mouseRowPos, mouseColPos) = app.rootMaskValue;
            set(app.imagePlot, 'CData', app.mask, 'alphadata', alphaImage);
            drawnow
        end
        
        function alphaImage = ComputeMaskAlphaImage(app)
            if app.showBackgroundColor
                alphaImage = ones(size(app.mask)) * app.maskAlpha;
            else
                alphaImage = (single(app.mask > app.bgMaskValue)) * app.maskAlpha;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenDirButton
        function OpenDirButtonPushed(app, event)
            
            app.inputImage = imread("00220110719002.tif");
            
            ComputeMaskFromInput(app);
            InitializeVariables(app);
            UpdateSegmentation(app);
        end

        % Value changed function: SliderMaskAlpha
        function SliderMaskAlphaValueChanged(app, event)
            value = app.SliderMaskAlpha.Value;
            app.maskAlpha = value;
            UpdateSegmentation(app);
        end

        % Value changed function: ShowmaskbackgroundcolorCheckBox
        function ShowmaskbackgroundcolorCheckBoxValueChanged(app, event)
            app.showBackgroundColor = app.ShowmaskbackgroundcolorCheckBox.Value;
            UpdateSegmentation(app);
        end

        % Callback function: ColorSelectorMaskBackground
        function ColorSelectorMaskBackgroundValueChanged(app, event)
            app.bgColor = app.ColorSelectorMaskBackground.Value;
            UpdateSegmentation(app);
        end

        % Callback function: ColorSelectorMaskShoot
        function ColorSelectorMaskShootValueChanged(app, event)
            app.shootColor = app.ColorSelectorMaskShoot.Value;
            UpdateSegmentation(app);
        end

        % Callback function: ColorSelectorMaskRoot
        function ColorSelectorMaskRootValueChanged(app, event)
            app.rootColor = app.ColorSelectorMaskRoot.Value;
            UpdateSegmentation(app);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'WindowTag')
                app.mouseDown = true;
                UpdateMaskFromMouse(app, event);
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'WindowTag')
                if(app.mouseDown)
                    UpdateMaskFromMouse(app, event);
                end
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'WindowTag')
                app.mouseDown = false;
                %UpdateSegmentation(app);
            end
        end

        % Selection changed function: ClickFunctionButtonGroup
        function ClickFunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ClickFunctionButtonGroup.SelectedObject;
            if(strcmp(selectedButton.Text, "Zoom In"))
                zoom(app.UIAxesSeg, 'on');
            else
                zoom(app.UIAxesSeg, 'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxesSeg = uiaxes(app.UIFigure);
            title(app.UIAxesSeg, 'Title')
            xlabel(app.UIAxesSeg, 'X')
            ylabel(app.UIAxesSeg, 'Y')
            zlabel(app.UIAxesSeg, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.OpenDirButton = uibutton(app.MainTab, 'push');
            app.OpenDirButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDirButtonPushed, true);
            app.OpenDirButton.Text = 'Open Dir';
            app.MaskAlphaLabel = uilabel(app.MainTab);
            app.MaskAlphaLabel.Text = 'Mask Alpha';
            app.SliderMaskAlpha = uislider(app.MainTab);
            app.SliderMaskAlpha.Limits = [0 1];
            app.SliderMaskAlpha.ValueChangedFcn = createCallbackFcn(app, @SliderMaskAlphaValueChanged, true);
            app.ClickFunctionButtonGroup = uibuttongroup(app.MainTab);
            app.ClickFunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClickFunctionButtonGroupSelectionChanged, true);
            app.ClickFunctionButtonGroup.Title = 'Click Function';
            app.Button = uitogglebutton(app.ClickFunctionButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.ClickFunctionButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.ClickFunctionButtonGroup);
            app.Button3.Text = 'Button3';
            app.ZoomInButton = uitogglebutton(app.ClickFunctionButtonGroup);
            app.ZoomInButton.Text = 'Zoom In';
            app.MiscTab = uitab(app.TabGroup);
            app.MiscTab.Title = 'Misc';
            app.ShowmaskbackgroundcolorCheckBox = uicheckbox(app.MiscTab);
            app.ShowmaskbackgroundcolorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowmaskbackgroundcolorCheckBoxValueChanged, true);
            app.ShowmaskbackgroundcolorCheckBox.Text = 'Show mask background color';
            app.ColorSelectorMaskBackground = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskBackground.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskBackgroundValueChanged, true);
            app.backgroundLabel = uilabel(app.MiscTab);
            app.backgroundLabel.Text = 'background';
            app.ColorSelectorMaskShoot = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskShoot.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskShootValueChanged, true);
            app.ColorSelectorMaskRoot = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskRoot.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskRootValueChanged, true);
            app.shootLabel = uilabel(app.MiscTab);
            app.shootLabel.Text = 'shoot';
            app.rootLabel = uilabel(app.MiscTab);
            app.rootLabel.Text = 'root';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlantSeg2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end